classdef DiamondStructureSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        InitialTurningWavesSpinner   matlab.ui.control.Spinner
        InitialTurningWavesSpinnerLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        WaveStructureTab             matlab.ui.container.Tab
        WaveStructureUIAxes          matlab.ui.control.UIAxes
        HodographTab                 matlab.ui.container.Tab
        HodographUIAxes              matlab.ui.control.UIAxes
        AreaPropertiesTab            matlab.ui.container.Tab
        AreaPropertiesTable          matlab.ui.control.Table
        DiamondStructureSolverLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Initial Settings
            app.RunButton.Enable = 'off';
            cla(app.WaveStructureUIAxes);
            cla(app.HodographUIAxes);
            app.AreaPropertiesTable.Data = {};
            drawnow;
            n = app.InitialTurningWavesSpinner.Value;       % Changable, n >= 2 and must be an integer
            area_num = 1.5*n^2+ 2.5*n - 1;
            pr = 0.8;    % Changable, 0 < pr < 1
            MB = 1.2;      % Changable, MB > 1
            gamma = 1.4;
            Me = solve_Me(MB,gamma,pr);
            omegaB = solve_omega(MB,gamma);
            omegae = solve_omega(Me,gamma);
            theta_total = omegaB - omegae;
            dtheta = theta_total/n;       % rad
            AREA = struct('number',{},'theta',{},'omega',{},'M',{},'I',{},'II',{},'mu',{});
            
            %% Solve for Numbers
            numbers = 1:area_num;
            
            %% Solve for Thetas
            for i = 1:n
                thetas(i) = i*dtheta;
                for j = 0:n-i
                    thetas(i*(n+1)+j) = j*dtheta;
                end
                for j = n-i+1:n
                    thetas(i*(n+1)+j) = (-j-2*i+2*n)*dtheta;
                end
            end
            num_flag = n;
            num_3 = n^2 + 2*n + 1;
            while num_flag > 1
                for k = 0:num_flag - 1
                    thetas(num_3) = -k*dtheta;
                    num_3 = num_3 + 1;
                end
                num_flag = num_flag - 1;
            end
            
            %% Solve for Omegas & Ms & (I,II)s
            for i = 1:n
                p_rank(i) = i;
                p_rank(i*(n+1)) = 2*i;
                for j = 1:n-i
                    p_rank(i*(n+1)+j) = 2*i + j;
                end
                for j = n-i+1:n
                    p_rank(i*(n+1)+j) = 2*n - j;
                end
            end
            num_flag = n;
            num_3 = n^2 + 2*n + 1;
            while num_flag > 1
                p_rank(num_3) = 2*(num_flag-1);
                for k = 1:num_flag - 1
                    num_3 = num_3 + 1;
                    p_rank(num_3) = 2*(num_flag-1) - k;
                end
                num_3 = num_3 + 1;
                num_flag = num_flag - 1;
            end
            for i = 1:area_num
                omegas(i) = -dtheta*p_rank(i) + omegaB;
                Ms(i) = solve_M(omegas(i),gamma);
                Is(i) = rad2deg(thetas(i) + omegas(i))/2+ 500;
                IIs(i) = rad2deg(thetas(i) - omegas(i))/2 + 500;
            end
            
            %% Solve for Mach Angles & Wave Angles
            for i = 1:area_num
                mus(i) = asin(1/Ms(i));
            end
            wave_angles = zeros(n,4);
            SB_angles = zeros(1,n+1);
            wave_angles(1,1) = asin(1/MB);
            for j = 2:n
                wave_angles(j,1) = thetas(j-1) + mus(j-1);
            end
            for k = 1:n
                wave_angles(k,2) = pi + thetas((n+1)*k-n) - mus((n+1)*k-n);
                wave_angles(k,3) = thetas((n+1)*k+n-1) + mus((n+1)*k+n-1);
            end
            wave_angles(1,4) = pi + thetas(n^2+n+1) - mus(n^2+n+1);
            for l = 1:n+1
                SB_angles(1,l) = thetas(l*(n+1)-1);
            end
            
            %% Fill in the Output
            AREA(1).number = 1;
            AREA(1).theta = 0;
            AREA(1).omega = omegaB;
            AREA(1).M = MB;
            AREA(1).I = rad2deg(0 + omegaB)/2+ 500;
            AREA(1).II = rad2deg(0 - omegaB)/2+ 500;
            AREA(1).mu = wave_angles(1,1);
            AREA(area_num+2).number = area_num+2;
            AREA(area_num+2).theta = 0;
            AREA(area_num+2).omega = omegaB;
            AREA(area_num+2).M = MB;
            AREA(area_num+2).I = rad2deg(0 + omegaB)/2+ 500;
            AREA(area_num+2).II = rad2deg(0 - omegaB)/2+ 500;
            AREA(area_num+2).mu = wave_angles(1,1);
            for i = 1:area_num
                AREA(i+1).number = numbers(i) + 1;
                AREA(i+1).theta = thetas(i);
                AREA(i+1).omega = omegas(i);
                AREA(i+1).M = Ms(i);
                AREA(i+1).I = Is(i);
                AREA(i+1).II = IIs(i);
                AREA(i+1).mu = mus(i);
            end
            
            %% Plot the Graph
            plot(app.WaveStructureUIAxes,[0 400],[100 100],'-k');hold(app.WaveStructureUIAxes,'on');
            x2(1)=0;y2(1)=0;
            for i = 2:n+1
                x1(i) = 100/tan(wave_angles(i-1,1));
                y1(i) = 100;
                plot(app.WaveStructureUIAxes,[0 x1(i)],[0 y1(i)],'-b');hold(app.WaveStructureUIAxes,'on');
            
                x2(i) = x1(i)+(y1(i)-y2(i-1)-tan(SB_angles(i-1))*(x1(i)-x2(i-1)))/(tan(SB_angles(i-1))-tan(wave_angles(i-1,2)));
                y2(i) = y2(i-1)+(x2(i)-x2(i-1))*tan(SB_angles(i-1));
                plot(app.WaveStructureUIAxes,[x1(i) x2(i)],[y1(i) y2(i)],'--b');hold(app.WaveStructureUIAxes,'on');
                plot(app.WaveStructureUIAxes,[x2(i-1) x2(i)],[y2(i-1) y2(i)],'-k');hold(app.WaveStructureUIAxes,'on');
            
                x3(i) = x2(i)+(100-y2(i))/tan(wave_angles(i-1,3));
                y3(i) = 100;
                plot(app.WaveStructureUIAxes,[x2(i) x3(i)],[y2(i) y3(i)],'-r');hold(app.WaveStructureUIAxes,'on');
            end
            x4(2) = x3(2)+(y3(2)-y2(n+1)-tan(SB_angles(n+1))*(x3(2)-x2(n+1)))/(tan(SB_angles(n+1))-tan(wave_angles(1,4)));
            y4(2) = y2(n+1)+(x4(2)-x2(n+1))*tan(SB_angles(n+1));
            for i = 2:n+1
                plot(app.WaveStructureUIAxes,[x3(i) x4(2)],[y3(i) y4(2)],'--r');hold(app.WaveStructureUIAxes,'on');
            end
            plot(app.WaveStructureUIAxes,[x2(n+1) x4(2)],[y2(n+1) y4(2)],'-k');
            
            %% Plot the Hodograph
            plot(app.HodographUIAxes,0,0,'.k');hold(app.HodographUIAxes,'on');
            x_point(1)=0;x_point(area_num+2)=0;
            y_point(1)=0;y_point(area_num+2)=0;
            for i = 1:area_num
                x_point(i+1) = cos(thetas(i))*(omegas(i)-omegaB);
                y_point(i+1) = sin(thetas(i))*(omegas(i)-omegaB);
                %offsetX = randn() * 0.5;
                %offsetY = randn() * 0.5;
                plot(app.HodographUIAxes,x_point(i+1),y_point(i+1),'.k');hold(app.HodographUIAxes,'on');
                %text(x_point(i+1)+offsetX,y_point(i+1)+offsetY,sprintf('%d',i+1));hold on;
            end
            determineline=zeros(n+1,n+1);
            valueline=zeros(n+1,n+1,3);
            for i =1:n+1
                plot(app.HodographUIAxes,[0 x_point(n*i+1)],[0,y_point(n*i+1)],'--c');hold(app.HodographUIAxes,'on');
                plot(app.HodographUIAxes,[0 x_point(n*(n+1)+i)],[0 y_point(n*(n+1)+i)],'--c');hold(app.HodographUIAxes,'on');
            end
            for i = 1:n+1
                for j = 1:i
                    line1_x(i,j)=x_point(i+n*(j-1));
                    line1_y(i,j)=y_point(i+n*(j-1));
                    valueline(i,j,1)=i+n*(j-1);
                    determineline(i,j)=determineline(i,j)+1;
                    %text(line1_x(i,j),line1_y(i,j),sprintf('%d',(n+1)*(i-1)+j));hold on;
                end
            end
            for i = 1:n+1
                line1_num = 0;
                for j = 1:i
                    line1_x(n+2-i,n+2-j)=x_point(n*(n+1)+i+line1_num);
                    line1_y(n+2-i,n+2-j)=y_point(n*(n+1)+i+line1_num);
                    valueline(n+2-i,n+2-j,2)=n*(n+1)+i+line1_num;
                    determineline(n+2-i,n+2-j)=determineline(n+2-i,n+2-j)+1;
                    line1_num=line1_num+n+1-j;
                end
                line1_num = 0;
            end
            for i = n:-1:2
                for j = n:-1:-i+n+2
                    valueline(i,j,3)=n*n+2-(n-j)*n-i+n;
                    determineline(i,j)=determineline(i,j)+1;
                end
            end
            determineline(n+1,n+1)=1;
            for i = 1:n+1
                for j = 1:n
                    plot(app.HodographUIAxes,[line1_x(i,j) line1_x(i,j+1)],[line1_y(i,j) line1_y(i,j+1)],'-r');hold(app.HodographUIAxes,'on');
                    plot(app.HodographUIAxes,[line1_x(j,i) line1_x(j+1,i)],[line1_y(j,i) line1_y(j+1,i)],'-r');hold(app.HodographUIAxes,'on');
                end
                for j = 1:n+1
                    if(determineline(i,j)==1)
                        if(valueline(i,j,2)==0)
                            text(app.HodographUIAxes,line1_x(i,j),line1_y(i,j),sprintf('%d',valueline(i,j,1)));hold(app.HodographUIAxes,'on');
                        else
                            text(app.HodographUIAxes,line1_x(i,j),line1_y(i,j),sprintf('%d',valueline(i,j,2)));hold(app.HodographUIAxes,'on');
                        end
                    elseif(determineline(i,j)==2)
                        if(valueline(i,j,3)==0)
                            text(app.HodographUIAxes,line1_x(i,j),line1_y(i,j),sprintf('%d,%d',valueline(i,j,1),valueline(i,j,2)));hold(app.HodographUIAxes,'on');
                        elseif(valueline(i,j,2)==0)
                            text(app.HodographUIAxes,line1_x(i,j),line1_y(i,j),sprintf('%d,%d',valueline(i,j,1),valueline(i,j,3)));hold(app.HodographUIAxes,'on');
                        else
                            text(app.HodographUIAxes,line1_x(i,j),line1_y(i,j),sprintf('%d,%d',valueline(i,j,2),valueline(i,j,3)));hold(app.HodographUIAxes,'on');
                        end
                    else
                        text(app.HodographUIAxes,line1_x(i,j),line1_y(i,j),sprintf('%d,%d,%d',valueline(i,j,1),valueline(i,j,3),valueline(i,j,2)));hold(app.HodographUIAxes,'on');
                    end
                end
            end
            
            %% Output Table
            data = struct2table(AREA);
            app.AreaPropertiesTable.Data = data;
            app.RunButton.Enable = 'on';
            drawnow;
            
            %% Functions
            function omega = solve_omega(M,gamma)
            omega = ((gamma+1)/(gamma-1))^0.5*atan(((gamma-1)/(gamma+1)*(M^2-1))^0.5) - atan((M^2-1)^0.5);
            end
            
            function M = solve_M(omega,gamma)
            eq = @(M_sym) ((gamma+1)/(gamma-1))^0.5*atan(((gamma-1)/(gamma+1)*(M_sym^2-1))^0.5) - atan((M_sym^2-1)^0.5) - omega;
            [M,~] = fzero(eq,[1.0001,10000]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiamondStructureSolverLabel = uilabel(app.UIFigure);
            app.DiamondStructureSolverLabel.Text = 'Diamond Structure Solver';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveStructureTab = uitab(app.TabGroup);
            app.WaveStructureTab.Title = 'Wave Structure';
            app.WaveStructureUIAxes = uiaxes(app.WaveStructureTab);
            app.WaveStructureUIAxes.XTickLabel = '';
            app.WaveStructureUIAxes.YTickLabel = '';
            app.HodographTab = uitab(app.TabGroup);
            app.HodographTab.Title = 'Hodograph';
            app.HodographUIAxes = uiaxes(app.HodographTab);
            app.HodographUIAxes.XTickLabel = '';
            app.HodographUIAxes.YTickLabel = '';
            app.AreaPropertiesTab = uitab(app.TabGroup);
            app.AreaPropertiesTab.Title = 'Area Properties';
            app.AreaPropertiesTable = uitable(app.AreaPropertiesTab);
            app.AreaPropertiesTable.ColumnName = {'number'; 'theta'; 'omega'; 'M'; 'I'; 'II'; 'mu'};
            app.AreaPropertiesTable.RowName = {};
            app.AreaPropertiesTable.ColumnSortable = true;
            app.InitialTurningWavesSpinnerLabel = uilabel(app.UIFigure);
            app.InitialTurningWavesSpinnerLabel.Text = 'Initial Turning Waves';
            app.InitialTurningWavesSpinner = uispinner(app.UIFigure);
            app.InitialTurningWavesSpinner.Limits = [2 Inf];
            app.InitialTurningWavesSpinner.Editable = 'off';
            app.InitialTurningWavesSpinner.Value = 2;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiamondStructureSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end